classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        YourMoveLabel            matlab.ui.control.Label
        RockButton               matlab.ui.control.Button
        PaperButton              matlab.ui.control.Button
        ScissorsButton           matlab.ui.control.Button
        Image                    matlab.ui.control.Image
        Image2                   matlab.ui.control.Image
        Image3                   matlab.ui.control.Image
        AIsMoveLabel             matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        Label2                   matlab.ui.control.Label
        PlayerWinsLabel          matlab.ui.control.Label
        AIWinsLabel              matlab.ui.control.Label
        DrawsLabel               matlab.ui.control.Label
        Label_2                  matlab.ui.control.Label
        Label_3                  matlab.ui.control.Label
        Label_4                  matlab.ui.control.Label
        win_lose_drawLabel       matlab.ui.control.Label
        ai_agentLabel            matlab.ui.control.Label
        SwitchLabel              matlab.ui.control.Label
        Switch                   matlab.ui.control.Switch
        ResetButton              matlab.ui.control.Button
        SaveFigureButton         matlab.ui.control.Button
        FocusLengthSpinnerLabel  matlab.ui.control.Label
        FocusLengthSpinner       matlab.ui.control.Spinner
        TotalRoundsLabel         matlab.ui.control.Label
        Label_5                  matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        %Init variables
            
            player_move = 0;
            previous_move = 0;
            previous_outcome = 0;
            
            AI_agent = 'random';
            round_counter = 0;
            
            wins = [0,0,0]; %wins vector, player, AI, draws
            win_matrix = [];
            
            player_win_message = 'You win!';
            AI_win_message = 'You lose!';
            draw_message = 'Its a draw';
            
            player_win_percent = [];
            AI_win_percent = [];
            draw_percent = []; % Description
            
            focus_length = 5;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.win_lose_drawLabel.Text = '';
            app.Label.Text = '';
            app.Label2.Text = '';
            app.ai_agentLabel.Text = '';
        end

        % Button pushed function: RockButton
        function RockButtonPushed(app, event)
            app.player_move = 'r';
            app.Label.Text = 'Rock';
            app.focus_length = app.FocusLengthSpinner.Value;
            
            
            if app.round_counter == 0 || app.round_counter == 1 || strcmp(app.Switch.Value,'Random')
                app.AI_agent = 'random';
            else              
                app.AI_agent = determine_AI_agent(app.win_matrix, app.round_counter, app.focus_length);              
            end
                    
            AI_move = determine_AI_move(app.previous_move, app.previous_outcome, app.AI_agent);
            
            if not(app.round_counter==0)
                app.win_matrix = simulate_AI_agents(app.player_move, app.previous_move, app.previous_outcome, app.win_matrix);
            end
                
            fprintf('Strategy: ')
            fprintf(app.AI_agent) 
            fprintf('\n')
            fprintf(num2str(app.focus_length));
          
            
            if AI_move == 'r'
            app.Label2.Text = 'Rock';
            end
            if AI_move == 'p'
            app.Label2.Text = 'Paper';
            end
            if AI_move == 's'
            app.Label2.Text = 'Scissors';
            end
            
            [winner] = determine_winner(app.player_move, AI_move);


            if strcmp(winner, 'player')
                fprintf(app.player_win_message)
                app.win_lose_drawLabel.Text = app.player_win_message;
                app.wins(1) = app.wins(1)+1;
            end
            if strcmp(winner, 'AI')
                fprintf(app.AI_win_message)
                app.win_lose_drawLabel.Text = app.AI_win_message;
                app.wins(2) = app.wins(2)+1;
            end
            if strcmp(winner, 'draw')
                fprintf(app.draw_message)
                app.win_lose_drawLabel.Text = app.draw_message;
                app.wins(3) = app.wins(3)+1;
            end
            
            
            fprintf('Player wins:%5d. AI wins:%5d. draws:%5d\n', app.wins(1), app.wins(2), app.wins(3))
            app.Label_2.Text = num2str(app.wins(1));
            app.Label_3.Text = num2str(app.wins(2));
            app.Label_4.Text = num2str(app.wins(3));
            
            app.ai_agentLabel.Text = app.AI_agent;
            
            app.previous_move  = app.player_move;
            app.previous_outcome = winner;
            
            app.round_counter = app.round_counter+1;
            app.Label_5.Text = num2str(app.round_counter);
            
            app.player_win_percent(app.round_counter) = (app.wins(1)/app.round_counter)*100;
            app.AI_win_percent(app.round_counter) = (app.wins(2)/app.round_counter)*100;
            app.draw_percent(app.round_counter) = (app.wins(3)/app.round_counter)*100;


            plot(app.UIAxes,0:app.round_counter-1, app.player_win_percent, '-g', 'LineWidth', 2);
            plot(app.UIAxes,0:app.round_counter-1, app.AI_win_percent, '-r', 'LineWidth', 2);
            plot(app.UIAxes,0:app.round_counter-1, app.draw_percent, '-m', 'LineWidth', 2); 
            legend(app.UIAxes,'Player Win %', 'AI Win %', 'Draw %');
        end

        % Button pushed function: ScissorsButton
        function ScissorsButtonPushed(app, event)
            app.player_move = 's';
            app.Label.Text = 'Scissors';
            app.focus_length = app.FocusLengthSpinner.Value;
            if app.round_counter == 0 || strcmp(app.Switch.Value,'Random')
                app.AI_agent = 'random';
            else
                app.win_matrix = simulate_AI_agents(app.player_move, app.previous_move, app.previous_outcome, app.win_matrix);
                app.AI_agent = determine_AI_agent(app.win_matrix, app.round_counter, app.focus_length);              
            end
                    
            AI_move = determine_AI_move(app.previous_move, app.previous_outcome, app.AI_agent);
                
            fprintf('Strategy: ')
            fprintf(app.AI_agent) 
            fprintf('\n')
            fprintf(num2str(app.focus_length));
         
            
            if AI_move == 'r'
            app.Label2.Text = 'Rock';
            end
            if AI_move == 'p'
            app.Label2.Text = 'Paper';
            end
            if AI_move == 's'
            app.Label2.Text = 'Scissors';
            end
            
           [winner] = determine_winner(app.player_move, AI_move);


            if strcmp(winner, 'player')
                fprintf(app.player_win_message)
                app.win_lose_drawLabel.Text = app.player_win_message;
                app.wins(1) = app.wins(1)+1;
            end
            if strcmp(winner, 'AI')
                fprintf(app.AI_win_message)
                app.win_lose_drawLabel.Text = app.AI_win_message;
                app.wins(2) = app.wins(2)+1;
            end
            if strcmp(winner, 'draw')
                fprintf(app.draw_message)
                app.win_lose_drawLabel.Text = app.draw_message;
                app.wins(3) = app.wins(3)+1;
            end
            
            fprintf('Player wins:%5d. AI wins:%5d. draws:%5d\n', app.wins(1), app.wins(2), app.wins(3))
            app.Label_2.Text = num2str(app.wins(1));
            app.Label_3.Text = num2str(app.wins(2));
            app.Label_4.Text = num2str(app.wins(3));
            
            app.ai_agentLabel.Text = app.AI_agent;
            
            app.previous_move  = app.player_move;
            app.previous_outcome = winner;
            
            app.round_counter = app.round_counter+1;
            app.Label_5.Text = num2str(app.round_counter);
            
            app.player_win_percent(app.round_counter) = (app.wins(1)/app.round_counter)*100;
            app.AI_win_percent(app.round_counter) = (app.wins(2)/app.round_counter)*100;
            app.draw_percent(app.round_counter) = (app.wins(3)/app.round_counter)*100;
                     
            plot(app.UIAxes,0:app.round_counter-1, app.player_win_percent, '-g', 'LineWidth', 2);
            plot(app.UIAxes,0:app.round_counter-1, app.AI_win_percent, '-r', 'LineWidth', 2);
            plot(app.UIAxes,0:app.round_counter-1, app.draw_percent, '-m', 'LineWidth', 2);   
            legend(app.UIAxes,'Player Win %', 'AI Win %', 'Draw %');
        end

        % Button pushed function: PaperButton
        function PaperButtonPushed(app, event)
            app.player_move = 'p';
            app.Label.Text = 'Paper';
            app.focus_length = app.FocusLengthSpinner.Value;
            if app.round_counter == 0 || strcmp(app.Switch.Value,'Random')
                app.AI_agent = 'random';
            else
                app.win_matrix = simulate_AI_agents(app.player_move, app.previous_move, app.previous_outcome, app.win_matrix);
                app.AI_agent = determine_AI_agent(app.win_matrix, app.round_counter, app.focus_length);              
            end
            
            AI_move = determine_AI_move(app.previous_move, app.previous_outcome, app.AI_agent);
                
            fprintf('Strategy: ')
            fprintf(app.AI_agent) 
            fprintf('\n')
            fprintf(num2str(app.focus_length));
            
            if AI_move == 'r'
            app.Label2.Text = 'Rock';
            end
            if AI_move == 'p'
            app.Label2.Text = 'Paper';
            end
            if AI_move == 's'
            app.Label2.Text = 'Scissors';
            end
            
            [winner] = determine_winner(app.player_move, AI_move);
            
           
            if strcmp(winner, 'player')
                fprintf(app.player_win_message)
                app.win_lose_drawLabel.Text = app.player_win_message;
                app.wins(1) = app.wins(1)+1;
            end
            if strcmp(winner, 'AI')
                fprintf(app.AI_win_message)
                app.win_lose_drawLabel.Text = app.AI_win_message;
                app.wins(2) = app.wins(2)+1;
            end
            if strcmp(winner, 'draw')
                fprintf(app.draw_message)
                app.win_lose_drawLabel.Text = app.draw_message;
                app.wins(3) = app.wins(3)+1;
            end
            
            fprintf('Player wins:%5d. AI wins:%5d. draws:%5d\n', app.wins(1), app.wins(2), app.wins(3))
            app.Label_2.Text = num2str(app.wins(1));
            app.Label_3.Text = num2str(app.wins(2));
            app.Label_4.Text = num2str(app.wins(3));
            
            app.ai_agentLabel.Text = app.AI_agent;
            
            app.previous_move  = app.player_move;
            app.previous_outcome = winner;
            
            app.round_counter = app.round_counter+1;
            app.Label_5.Text = num2str(app.round_counter);
            
            app.player_win_percent(app.round_counter) = (app.wins(1)/app.round_counter)*100;
            app.AI_win_percent(app.round_counter) = (app.wins(2)/app.round_counter)*100;
            app.draw_percent(app.round_counter) = (app.wins(3)/app.round_counter)*100;
                       
            plot(app.UIAxes,0:app.round_counter-1, app.player_win_percent, '-g', 'LineWidth', 2);
            plot(app.UIAxes,0:app.round_counter-1, app.AI_win_percent, '-r', 'LineWidth', 2);
            plot(app.UIAxes,0:app.round_counter-1, app.draw_percent, '-m', 'LineWidth', 2);
            legend(app.UIAxes,'Player Win %', 'AI Win %', 'Draw %');
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.win_lose_drawLabel.Text = '';
            app.Label.Text = '';
            app.Label2.Text = '';
            app.ai_agentLabel.Text = '';
            
            app.player_move = 0;
            app.previous_move = 0;
            app.previous_outcome = 0;
            
            app.AI_agent = 'random';
            app.round_counter = 0;
            
            app.wins = [0,0,0]; %wins vector, player, AI, draws
            app.win_matrix = [];
            
            app.player_win_message = 'You win!';
            app.AI_win_message = 'You lose!';
            app.draw_message = 'Its a draw';
            
            app.player_win_percent = [];
            app.AI_win_percent = [];
            app.draw_percent = []; % Description
            
            app.Label_2.Text = num2str(app.wins(1));
            app.Label_3.Text = num2str(app.wins(2));
            app.Label_4.Text = num2str(app.wins(3));
            
            app.ai_agentLabel.Text = '';
            
            app.Label_5.Text = num2str(app.round_counter);
            
            cla(app.UIAxes);
        end

        % Button pushed function: SaveFigureButton
        function SaveFigureButtonPushed(app, event)
            newfigure = figure;
            copyobj(app.UIAxes, newfigure);
            
        end

        % Value changed function: FocusLengthSpinner
        function FocusLengthSpinnerValueChanged(app, event)
            value = app.FocusLengthSpinner.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.YourMoveLabel = uilabel(app.UIFigure);
            app.YourMoveLabel.Text = 'Your Move:';
            app.RockButton = uibutton(app.UIFigure, 'push');
            app.RockButton.ButtonPushedFcn = createCallbackFcn(app, @RockButtonPushed, true);
            app.RockButton.Text = 'Rock';
            app.PaperButton = uibutton(app.UIFigure, 'push');
            app.PaperButton.ButtonPushedFcn = createCallbackFcn(app, @PaperButtonPushed, true);
            app.PaperButton.Text = 'Paper';
            app.ScissorsButton = uibutton(app.UIFigure, 'push');
            app.ScissorsButton.ButtonPushedFcn = createCallbackFcn(app, @ScissorsButtonPushed, true);
            app.ScissorsButton.Text = 'Scissors';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'rock.png';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'paper.png';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'scissors.png';
            app.AIsMoveLabel = uilabel(app.UIFigure);
            app.AIsMoveLabel.Text = 'AI''s Move:';
            app.Label = uilabel(app.UIFigure);
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            app.PlayerWinsLabel = uilabel(app.UIFigure);
            app.PlayerWinsLabel.Text = 'Player Wins:';
            app.AIWinsLabel = uilabel(app.UIFigure);
            app.AIWinsLabel.Text = 'AI Wins:';
            app.DrawsLabel = uilabel(app.UIFigure);
            app.DrawsLabel.Text = 'Draws:';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '0';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '0';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '0';
            app.win_lose_drawLabel = uilabel(app.UIFigure);
            app.win_lose_drawLabel.Text = 'win_lose_draw';
            app.ai_agentLabel = uilabel(app.UIFigure);
            app.ai_agentLabel.Text = 'ai_agent';
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = '';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Random', 'AI'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Random';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SaveFigureButton = uibutton(app.UIFigure, 'push');
            app.SaveFigureButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButtonPushed, true);
            app.SaveFigureButton.Text = 'Save Figure';
            app.FocusLengthSpinnerLabel = uilabel(app.UIFigure);
            app.FocusLengthSpinnerLabel.Text = 'FocusLength';
            app.FocusLengthSpinner = uispinner(app.UIFigure);
            app.FocusLengthSpinner.ValueChangedFcn = createCallbackFcn(app, @FocusLengthSpinnerValueChanged, true);
            app.FocusLengthSpinner.Value = 5;
            app.TotalRoundsLabel = uilabel(app.UIFigure);
            app.TotalRoundsLabel.Text = 'Total Rounds:';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '0';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Win percentages')
            xlabel(app.UIAxes, 'Round')
            ylabel(app.UIAxes, 'Win percentage')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end